classdef test_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        PlayOriginalButton            matlab.ui.control.Button
        PlayEchoedButton              matlab.ui.control.Button
        PlayRegeneratedButton         matlab.ui.control.Button
        DealwithButton                matlab.ui.control.Button
        FilterButtonGroup             matlab.ui.container.ButtonGroup
        RLSButton                     matlab.ui.control.RadioButton
        LMSButton                     matlab.ui.control.RadioButton
        FilteredXLMSButton            matlab.ui.control.RadioButton
        LengthEditFieldLabel          matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        ForgettingFactorSpinnerLabel  matlab.ui.control.Label
        ForgettingFactorSpinner       matlab.ui.control.Spinner
        StepsizeSpinnerLabel          matlab.ui.control.Label
        StepsizeSpinner               matlab.ui.control.Spinner
        EchoCancellationLabel         matlab.ui.control.Label
        Gauge4Label                   matlab.ui.control.Label
        Gauge4                        matlab.ui.control.LinearGauge
        Gauge5Label                   matlab.ui.control.Label
        Gauge5                        matlab.ui.control.LinearGauge
        Gauge6Label                   matlab.ui.control.Label
        Gauge6                        matlab.ui.control.LinearGauge
        SpectrogramSwitchLabel        matlab.ui.control.Label
        SpectrogramSwitch             matlab.ui.control.Switch
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global FilterType;
            FilterType=0;
        end

        % Button pushed function: DealwithButton
        function DealwithButtonPushed(app, event)
            % clear all;
            % Audio Input
            global x;
            global y;
            global res;
            global Fs;
            [x,Fs] = audioread('handel.wav');
            %sound(x,Fs);
            %pause(10);
            [y,Fs]=audioread('handel_echo.wav');
            
            %Echo cancellation by Adaptive Filter
            %mu= 0.008;            % LMS step size.
            %ha = dsp.LMSFilter('Length',32,'StepSize',mu);
            
            length=app.LengthEditField.Value;
            ff=app.ForgettingFactorSpinner.Value;
            mu=app.StepsizeSpinner.Value;
            global FilterType;
            switch FilterType
                case 0
                    ha=dsp.RLSFilter('Length',length,'ForgettingFactor',ff);
                case 1
                    ha=dsp.LMSFilter('Length',length,'StepSize',mu);
                case 2
                    ha=dsp.FilteredXLMSFilter('Length',length,'StepSize',mu);
            end
            %ha = dsp.RLSFilter('Length',32,'ForgettingFactor', 0.98);
            %ha= adaptfilt.lms(32,mu);
            %[res,e] = filter(ha,p,z);
            [res,e] = ha(x,y);
            
            %Regenerated Sound from Echoed Sound
            audiowrite('handle Regenerated.wav',res,Fs);
            app.PlayRegeneratedButton.Enable='on';
            app.SpectrogramSwitch.Enable='on';
                        
            %Plots
            %Original Signal
            %subplot(3,1,1); plot(x);
            %uiaxes(app.UIAxes);
%             app.UIAxes.
%             axes(app.UIAxes);
%             hold(app.UIAxes,'on');

%             [s,f,t]=spectrogram(x,Fs);
%             axesSpec=app.UIAxes;
%             axesSpec.XScale='linear';
%             plot(axesSpec,f,s);
              specgram(x);
%              spectrogram(x);
%             plot(app.UIAxes,x);
%             title(app.UIAxes,'Original Signal');
%             xlabel(app.UIAxes,'Time Index'); ylabel(app.UIAxes,'Signal Value');
            
            %Echoed Signal
            %subplot(3,1,2); plot(y);
            plot(app.UIAxes2,y);
            title(app.UIAxes2,'Echoed Signal');
            xlabel(app.UIAxes2,'Time Index'); ylabel(app.UIAxes2,'Signal Value');
            
            %Regenerated Signal
            %subplot(3,1,3); plot(res);
            plot(app.UIAxes3,res);
            title(app.UIAxes3,'Regenerated Signal');
            xlabel(app.UIAxes3,'Time Index'); ylabel(app.UIAxes3,'Signal Value');
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            global x;
            global Fs;
            sound(x,Fs);
            pause(10);
        end

        % Button pushed function: PlayEchoedButton
        function PlayEchoedButtonPushed(app, event)
            global y;
            global Fs;
            sound(y,Fs);
            pause(10);
        end

        % Button pushed function: PlayRegeneratedButton
        function PlayRegeneratedButtonPushed(app, event)
            global res;
            global Fs;
            sound(res,Fs);
            pause(10);
        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterButtonGroup.SelectedObject;
            app.PlayRegeneratedButton.Enable='off';
            app.SpectrogramSwitch.Enable='off';
            global FilterType;
            switch selectedButton
                case app.RLSButton
                    app.ForgettingFactorSpinner.Enable='on';
                    %app.LengthEditField.Value=50;
                    app.StepsizeSpinner.Enable='off';
                    FilterType=0;
                    %ha=dsp.RLSFilter('Length',32,'Forgetting Factor',ff);
                case app.LMSButton
                    app.ForgettingFactorSpinner.Enable='off';
                    app.StepsizeSpinner.Enable='on';
                    %app.LengthEditField.Value=100;
                    FilterType=1;
                    %ha=dsp.LMSFilter('Length',32,'StepSize',mu);
                case app.FilteredXLMSButton
                    app.ForgettingFactorSpinner.Enable='off';
                    app.StepsizeSpinner.Enable='on';
                    FilterType=2;
                    %ha=dsp.FilteredXLMSFilter('Length',32,'StepSize',mu);
            end
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            value = app.LengthEditField.Value;
            app.PlayRegeneratedButton.Enable='off';
            app.SpectrogramSwitch.Enable='off';
        end

        % Value changed function: ForgettingFactorSpinner
        function ForgettingFactorSpinnerValueChanged(app, event)
            value = app.ForgettingFactorSpinner.Value;
            app.PlayRegeneratedButton.Enable='off';
            app.SpectrogramSwitch.Enable='off';
        end

        % Value changed function: StepsizeSpinner
        function StepsizeSpinnerValueChanged(app, event)
            value = app.StepsizeSpinner.Value;
            app.PlayRegeneratedButton.Enable='off';
            app.SpectrogramSwitch.Enable='off';
        end

        % Value changed function: SpectrogramSwitch
        function SpectrogramSwitchValueChanged(app, event)
            value = app.SpectrogramSwitch.Value;
            global x;
            global y;
            global res;
            if value == "Off"
                %Plots
                %Original Signal
                %subplot(3,1,1); plot(x);
                plot(app.UIAxes,x);
                title(app.UIAxes,'Original Signal');
                xlabel(app.UIAxes,'Time Index'); ylabel(app.UIAxes,'Signal Value');
                
                %Echoed Signal
                %subplot(3,1,2); plot(y);
                plot(app.UIAxes2,y);
                title(app.UIAxes2,'Echoed Signal');
                xlabel(app.UIAxes2,'Time Index'); ylabel(app.UIAxes2,'Signal Value');
                
                %Regenerated Signal
                %subplot(3,1,3); plot(res);
                plot(app.UIAxes3,res);
                title(app.UIAxes3,'Regenerated Signal');
                xlabel(app.UIAxes3,'Time Index'); ylabel(app.UIAxes3,'Signal Value');
            else
                spectrogram(app.UIAxes,x);
                spectrogram(app.UIAxes2,y);
                spectrogram(app.UIAxes3,res);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.PlayOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayEchoedButton = uibutton(app.UIFigure, 'push');
            app.PlayEchoedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayEchoedButtonPushed, true);
            app.PlayEchoedButton.Text = 'Play Echoed';
            app.PlayRegeneratedButton = uibutton(app.UIFigure, 'push');
            app.PlayRegeneratedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRegeneratedButtonPushed, true);
            app.PlayRegeneratedButton.Enable = 'off';
            app.PlayRegeneratedButton.Text = 'Play Regenerated';
            app.DealwithButton = uibutton(app.UIFigure, 'push');
            app.DealwithButton.ButtonPushedFcn = createCallbackFcn(app, @DealwithButtonPushed, true);
            app.DealwithButton.Text = 'Deal with';
            app.FilterButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'Filter';
            app.RLSButton = uiradiobutton(app.FilterButtonGroup);
            app.RLSButton.Text = 'RLS';
            app.RLSButton.Value = true;
            app.LMSButton = uiradiobutton(app.FilterButtonGroup);
            app.LMSButton.Text = 'LMS';
            app.FilteredXLMSButton = uiradiobutton(app.FilterButtonGroup);
            app.FilteredXLMSButton.Text = 'FilteredXLMS';
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditField.Limits = [0 1024];
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.LengthEditField.Value = 32;
            app.ForgettingFactorSpinnerLabel = uilabel(app.UIFigure);
            app.ForgettingFactorSpinnerLabel.Text = 'Forgetting Factor';
            app.ForgettingFactorSpinner = uispinner(app.UIFigure);
            app.ForgettingFactorSpinner.Step = 0.001;
            app.ForgettingFactorSpinner.Limits = [0 1];
            app.ForgettingFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @ForgettingFactorSpinnerValueChanged, true);
            app.ForgettingFactorSpinner.Value = 0.999;
            app.StepsizeSpinnerLabel = uilabel(app.UIFigure);
            app.StepsizeSpinnerLabel.Enable = 'off';
            app.StepsizeSpinnerLabel.Text = 'Stepsize';
            app.StepsizeSpinner = uispinner(app.UIFigure);
            app.StepsizeSpinner.Step = 0.001;
            app.StepsizeSpinner.Limits = [0 1];
            app.StepsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @StepsizeSpinnerValueChanged, true);
            app.StepsizeSpinner.Enable = 'off';
            app.StepsizeSpinner.Value = 0.008;
            app.EchoCancellationLabel = uilabel(app.UIFigure);
            app.EchoCancellationLabel.Text = 'Echo Cancellation';
            app.Gauge4Label = uilabel(app.UIFigure);
            app.Gauge4Label.Text = 'Gauge4';
            app.Gauge4 = uigauge(app.UIFigure, 'linear');
            app.Gauge5Label = uilabel(app.UIFigure);
            app.Gauge5Label.Text = 'Gauge5';
            app.Gauge5 = uigauge(app.UIFigure, 'linear');
            app.Gauge6Label = uilabel(app.UIFigure);
            app.Gauge6Label.Text = 'Gauge6';
            app.Gauge6 = uigauge(app.UIFigure, 'linear');
            app.SpectrogramSwitchLabel = uilabel(app.UIFigure);
            app.SpectrogramSwitchLabel.Enable = 'off';
            app.SpectrogramSwitchLabel.Text = 'Spectrogram';
            app.SpectrogramSwitch = uiswitch(app.UIFigure, 'slider');
            app.SpectrogramSwitch.ValueChangedFcn = createCallbackFcn(app, @SpectrogramSwitchValueChanged, true);
            app.SpectrogramSwitch.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = test_v1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end